classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        AnalysisTargetGroup             matlab.ui.container.ButtonGroup
        PressurevsTemperatureButton     matlab.ui.control.RadioButton
        TemperaturevsTimeButton         matlab.ui.control.RadioButton
        PressurevsTimeButton            matlab.ui.control.RadioButton
        FileNameLabel                   matlab.ui.control.Label
        MultiplierLabel                 matlab.ui.control.Label
        SensitivitySlider               matlab.ui.control.Slider
        OutlierSTDLabel                 matlab.ui.control.Label
        IdentifyOutliersButton          matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        BivariateAnalysisTab            matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        PressureVsTempCorrelationLabel  matlab.ui.control.Label
        TimeSeriesCorrelationLabel      matlab.ui.control.Label
        PressureVsTempAxes              matlab.ui.control.UIAxes
        TimeSeriesAxes                  matlab.ui.control.UIAxes
        UnivariateAnalysisTab           matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        PressureStatisticsLabel         matlab.ui.control.Label
        TemperatureStatisticsLabel      matlab.ui.control.Label
        TemperatureBoxPlotAxes          matlab.ui.control.UIAxes
        PressureBoxPlotAxes             matlab.ui.control.UIAxes
        PressureHistogramAxes           matlab.ui.control.UIAxes
        TemperatureHistogramAxes        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = private)
        OutliersVisible = false; % Tracks if outliers are currently displayed
        TimeData = [];
        TemperatureData = [];
        PressureData = [];
    end
    
    methods (Access = private)
        % Main function to handle outlier detection and plotting (no changes needed here)
        function updateOutliers(app)
            if isempty(app.TemperatureData)
                return; % No data loaded
            end
            
            % First, clear any outliers that might exist on ANY plot
            app.hideOutliers();
            
            % Get the analysis target from the selected radio button
            target = app.AnalysisTargetGroup.SelectedObject.Text;
            
            % Get sensitivity
            std_multiplier = app.SensitivitySlider.Value;
            app.MultiplierLabel.Text = sprintf('%.2f', std_multiplier);
            
            switch target
                case {'Temperature vs. Time', 'Pressure vs. Time'}
                    % --- LOGIC FOR TIME SERIES PLOTS ---
                    targetAxes = app.TimeSeriesAxes;
                    
                    % --- MODIFIED: Directly find the line by its DisplayName ---
                    if strcmp(target, 'Temperature vs. Time')
                        dataLine = findobj(targetAxes, 'DisplayName', 'Temperature');
                        y_axis_side = 'left';
                    else % Pressure vs. Time
                        dataLine = findobj(targetAxes, 'DisplayName', 'Pressure');
                        y_axis_side = 'right';
                    end
                    
                    if isempty(dataLine)
                        return; % Correct data line not found
                    end
                    x_data = dataLine.XData;
                    y_data = dataLine.YData;
                    
                    % Use moving average for time series data
                    window_size = 20;
                    moving_avg = movmean(y_data, window_size);
                    residuals = y_data - moving_avg;
                    
                case 'Pressure vs. Temperature'
                    % --- LOGIC FOR SCATTER PLOT ---
                    targetAxes = app.PressureVsTempAxes;
                    x_data = app.TemperatureData;
                    y_data = app.PressureData;
                    
                    % Use linear regression for scatter data
                    p = polyfit(x_data, y_data, 1); % Fit a 1st-degree polynomial (a line)
                    y_fit = polyval(p, x_data);     % Calculate the y-values on that line
                    residuals = y_data - y_fit;     % Find the distance from the line
            end
            
            % --- COMMON LOGIC: Identify and plot outliers based on residuals ---
            residual_std = std(residuals);
            threshold = std_multiplier * residual_std;
            outlier_indices = find(abs(residuals) > threshold);
            
            if ~isempty(outlier_indices)
                x_outliers = x_data(outlier_indices);
                y_outliers = y_data(outlier_indices);
                
                % If plotting on TimeSeriesAxes, ensure correct yyaxis is active
                if isequal(targetAxes, app.TimeSeriesAxes)
                    yyaxis(targetAxes, y_axis_side);
                end
                
                hold(targetAxes, 'on');
                plot(targetAxes, x_outliers, y_outliers, 'ro', ...
                    'MarkerSize', 10, 'LineWidth', 2, ...
                    'DisplayName', 'Outliers', 'Tag', 'outlier_plot');
                hold(targetAxes, 'off');
            end
            
            legend(targetAxes, 'show');
        end

        % Function to specifically hide the outlier circles
        function hideOutliers(app)
            % Find ALL outlier plots in the entire figure and delete them
            previous_outliers = findobj(app.UIFigure, 'Tag', 'outlier_plot');
            if ~isempty(previous_outliers)
                delete(previous_outliers);
            end
            % Refresh legends on both axes to revert to their original state
            legend(app.TimeSeriesAxes, 'show');
            legend(app.PressureVsTempAxes, 'show');
        end

        % Helper function to clean a single column of data
        function cleaned_data = cleanDataColumn(~, raw_data)
            % If data is not numeric (e.g., a cell array with 'ERROR'),
            % convert it. str2double will turn non-numeric text into NaN.
            if ~isnumeric(raw_data)
                numeric_data = str2double(raw_data);
            else
                numeric_data = raw_data;
            end
            
            % Check if there are any NaNs that need to be filled
            if any(isnan(numeric_data))
                % Use linear interpolation to fill missing values
                % 'fillmissing' requires a vector, ensure it's a column vector
                cleaned_data = fillmissing(numeric_data(:), 'linear');
            else
                cleaned_data = numeric_data;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Hide the sensitivity slider and its label initially
            app.SensitivitySlider.Visible = 'off';
            app.MultiplierLabel.Visible = 'off';
            app.OutlierSTDLabel.Visible = 'off';
            app.AnalysisTargetGroup.Visible = 'off';
            
            % Hide plots and identify button until data is loaded
            app.IdentifyOutliersButton.Visible = 'off';
            app.TimeSeriesAxes.Visible = 'off';
            app.PressureVsTempAxes.Visible = 'off';
            app.TimeSeriesCorrelationLabel.Visible = 'off';
            app.PressureVsTempCorrelationLabel.Visible = 'off';
            
            % Hide univariate plots until data is loaded
            app.TemperatureHistogramAxes.Visible = 'off';
            app.TemperatureBoxPlotAxes.Visible = 'off';
            app.PressureHistogramAxes.Visible = 'off';
            app.PressureBoxPlotAxes.Visible = 'off';
            
            % --- NEW: Hide statistics labels until data is loaded ---
            app.TemperatureStatisticsLabel.Visible = 'off';
            app.PressureStatisticsLabel.Visible = 'off';
            
            % Clear correlation labels
            app.TimeSeriesCorrelationLabel.Text = '';
            app.PressureVsTempCorrelationLabel.Text = '';
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [fileName, filePath] = uigetfile('*.csv', 'Select a Reactor Data CSV file');
            
            if isequal(fileName, 0)
                return;
            end
            
            fullPath = fullfile(filePath, fileName);
            
            try
                % --- MODIFIED: Use detectImportOptions to better handle mixed data types ---
                opts = detectImportOptions(fullPath);
                % Preview the data to find the relevant columns by name
                colNames = opts.VariableNames;
                timeColIdx = find(contains(colNames, 'Time', 'IgnoreCase', true), 1);
                tempColIdx = find(contains(colNames, 'Temp', 'IgnoreCase', true), 1);
                pressureColIdx = find(contains(colNames, 'Pressure', 'IgnoreCase', true), 1);
                
                if isempty(timeColIdx) || isempty(tempColIdx) || isempty(pressureColIdx)
                    uialert(app.UIFigure, 'Selected file must have columns containing "Time", "Temp", and "Pressure".', 'Invalid File Format');
                    return;
                end
                
                % Ensure the columns with potential errors are read as text
                opts.VariableTypes{tempColIdx} = 'char';
                opts.VariableTypes{pressureColIdx} = 'char';
                
                data = readtable(fullPath, opts);
                
                % Extract the raw data (which will be cell arrays for temp/pressure)
                rawTimeData = data{:, timeColIdx};
                rawTempData = data{:, tempColIdx};
                rawPressureData = data{:, pressureColIdx};
                
                % --- REVISED: DATA CONVERSION & CLEANING LOGIC ---
                % First, convert all data to a numeric format.
                % str2double will handle text like 'ERROR' by converting it to NaN.
                numericTempData = str2double(rawTempData);
                numericPressureData = str2double(rawPressureData);
                
                % Now, check if the conversion resulted in any NaNs (from 'ERROR' or actual 'NaN' text)
                isTempCorrupted = any(isnan(numericTempData));
                isPressureCorrupted = any(isnan(numericPressureData));
                
                if isTempCorrupted || isPressureCorrupted
                    selection = uiconfirm(app.UIFigure, ...
                        'Corrupted or missing data detected (e.g., "ERROR", NaN). Would you like to clean the data using linear interpolation?', ...
                        'Data Cleaning Required', ...
                        'Options', {'Yes, Clean Data', 'No, Cancel'}, ...
                        'DefaultOption', 1, 'CancelOption', 2);
                    
                    if strcmp(selection, 'Yes, Clean Data')
                        % Use fillmissing to clean the data that contains NaNs
                        app.TemperatureData = fillmissing(numericTempData(:), 'linear');
                        app.PressureData = fillmissing(numericPressureData(:), 'linear');
                        app.TimeData = rawTimeData; % Assume time data is clean
                        
                        uialert(app.UIFigure, 'Data has been successfully cleaned.', 'Cleaning Complete', 'Icon', 'success');
                    else
                        % User chose not to clean the data
                        uialert(app.UIFigure, 'Data loading cancelled. Cannot plot corrupted data.', 'Operation Cancelled');
                        return; % Stop the function
                    end
                else
                    % Data is clean, no NaNs found after conversion. Assign it directly.
                    app.TimeData = rawTimeData;
                    app.TemperatureData = numericTempData;
                    app.PressureData = numericPressureData;
                end
                
                % --- Plotting and analysis code proceeds from here with clean data ---
                
                % Make plots and controls visible
                app.IdentifyOutliersButton.Visible = 'on';
                app.TimeSeriesAxes.Visible = 'on';
                app.PressureVsTempAxes.Visible = 'on';
                app.TimeSeriesCorrelationLabel.Visible = 'on';
                app.PressureVsTempCorrelationLabel.Visible = 'on';
                app.TemperatureHistogramAxes.Visible = 'on';
                app.TemperatureBoxPlotAxes.Visible = 'on';
                app.PressureHistogramAxes.Visible = 'on';
                app.PressureBoxPlotAxes.Visible = 'on';
                app.TemperatureStatisticsLabel.Visible = 'on';
                app.PressureStatisticsLabel.Visible = 'on';
                
                % PLOT 1: Time Series Plot
                cla(app.TimeSeriesAxes, 'reset');
                yyaxis(app.TimeSeriesAxes, 'left');
                plot(app.TimeSeriesAxes, app.TimeData, app.TemperatureData, 'b-', 'LineWidth', 1.5, 'DisplayName', 'Temperature');
                ylabel(app.TimeSeriesAxes, 'Temperature (°C)', 'Color', 'b');
                app.TimeSeriesAxes.YColor = 'b';
        
                yyaxis(app.TimeSeriesAxes, 'right');
                plot(app.TimeSeriesAxes, app.TimeData, app.PressureData, 'r-', 'LineWidth', 1.5, 'DisplayName', 'Pressure');
                ylabel(app.TimeSeriesAxes, 'Pressure (Pa)', 'Color', 'r');
                app.TimeSeriesAxes.YColor = 'r';
        
                title(app.TimeSeriesAxes, 'Pressure & Temperature vs. Time');
                xlabel(app.TimeSeriesAxes, 'Time (sec)');
                legend(app.TimeSeriesAxes, 'Location', 'northwest');
                grid(app.TimeSeriesAxes, 'on');
                
                % CALCULATE AND DISPLAY CORRELATIONS FOR PLOT 1
                R_temp_time_matrix = corrcoef(app.TimeData, app.TemperatureData);
                R_temp_time = R_temp_time_matrix(1, 2);
                R_pres_time_matrix = corrcoef(app.TimeData, app.PressureData);
                R_pres_time = R_pres_time_matrix(1, 2);
                app.TimeSeriesCorrelationLabel.Text = sprintf('Correlations (R): Temp vs. Time = %+.4f, Pressure vs. Time = %+.4f', R_temp_time, R_pres_time);
                
                % PLOT 2: Pressure vs. Temperature Plot
                cla(app.PressureVsTempAxes, 'reset');
                plot(app.PressureVsTempAxes, app.TemperatureData, app.PressureData, 'k.', 'MarkerSize', 10, 'DisplayName', 'Data');
                title(app.PressureVsTempAxes, 'Pressure vs. Temperature');
                xlabel(app.PressureVsTempAxes, 'Temperature (°C)');
                ylabel(app.PressureVsTempAxes, 'Pressure (Pa)');
                grid(app.PressureVsTempAxes, 'on');
                legend(app.PressureVsTempAxes, 'show');
                
                % CALCULATE AND DISPLAY CORRELATION FOR PLOT 2
                R_pres_temp_matrix = corrcoef(app.TemperatureData, app.PressureData);
                R_pres_temp = R_pres_temp_matrix(1, 2);
                app.PressureVsTempCorrelationLabel.Text = sprintf('Correlation (R): %+.4f', R_pres_temp);
                
                % PLOTTING AND STATISTICS CODE FOR UNIVARIATE TAB
                
                % PLOT 3: Temperature Histogram
                cla(app.TemperatureHistogramAxes, 'reset');
                histogram(app.TemperatureHistogramAxes, app.TemperatureData);
                title(app.TemperatureHistogramAxes, 'Temperature Distribution');
                xlabel(app.TemperatureHistogramAxes, 'Temperature (°C)');
                ylabel(app.TemperatureHistogramAxes, 'Frequency');
                grid(app.TemperatureHistogramAxes, 'on');
                % PLOT 4: Temperature Box Plot
                cla(app.TemperatureBoxPlotAxes, 'reset');
                boxchart(app.TemperatureBoxPlotAxes, app.TemperatureData, 'Orientation', 'horizontal');
                title(app.TemperatureBoxPlotAxes, 'Temperature Box Plot');
                xlabel(app.TemperatureBoxPlotAxes, 'Temperature (°C)');
                ylabel(app.TemperatureBoxPlotAxes, '');
                grid(app.TemperatureBoxPlotAxes, 'on');
                % PLOT 5: Pressure Histogram
                cla(app.PressureHistogramAxes, 'reset');
                histogram(app.PressureHistogramAxes, app.PressureData);
                title(app.PressureHistogramAxes, 'Pressure Distribution');
                xlabel(app.PressureHistogramAxes, 'Pressure (Pa)');
                ylabel(app.PressureHistogramAxes, 'Frequency');
                grid(app.PressureHistogramAxes, 'on');
                % PLOT 6: Pressure Box Plot
                cla(app.PressureBoxPlotAxes, 'reset');
                boxchart(app.PressureBoxPlotAxes, app.PressureData, 'Orientation', 'horizontal');
                title(app.PressureBoxPlotAxes, 'Pressure Box Plot');
                xlabel(app.PressureBoxPlotAxes, 'Pressure (Pa)');
                ylabel(app.PressureBoxPlotAxes, '');
                grid(app.PressureBoxPlotAxes, 'on');
                
                % --- NEW STATISTICS CALCULATION ---
                % Temperature Statistics
                temp_mean = mean(app.TemperatureData);
                temp_median = median(app.TemperatureData);
                temp_std = std(app.TemperatureData);
                temp_min = min(app.TemperatureData);
                temp_max = max(app.TemperatureData);
                temp_quartiles = quantile(app.TemperatureData, [0.25 0.5 0.75]);
                
                temp_stats_text = {
                    sprintf('Mean: %.2f', temp_mean), ...
                    sprintf('Median: %.2f', temp_median), ...
                    sprintf('Std Dev: %.2f', temp_std), ...
                    sprintf('Min: %.2f', temp_min), ...
                    sprintf('Max: %.2f', temp_max), ...
                    sprintf('Quartiles: %.2f, %.2f, %.2f', temp_quartiles(1), temp_quartiles(2), temp_quartiles(3))
                };
                app.TemperatureStatisticsLabel.Text = temp_stats_text;
                
                % Pressure Statistics
                pres_mean = mean(app.PressureData);
                pres_median = median(app.PressureData);
                pres_std = std(app.PressureData);
                pres_min = min(app.PressureData);
                pres_max = max(app.PressureData);
                pres_quartiles = quantile(app.PressureData, [0.25 0.5 0.75]);
                
                pres_stats_text = {
                    sprintf('Mean: %.2f', pres_mean), ...
                    sprintf('Median: %.2f', pres_median), ...
                    sprintf('Std Dev: %.2f', pres_std), ...
                    sprintf('Min: %.2f', pres_min), ...
                    sprintf('Max: %.2f', pres_max), ...
                    sprintf('Quartiles: %.2f, %.2f, %.2f', pres_quartiles(1), pres_quartiles(2), pres_quartiles(3))
                };
                app.PressureStatisticsLabel.Text = pres_stats_text;
                
                app.FileNameLabel.Text = {'Loaded File:', strrep(fileName, '_', ' ')};
                
            catch ME
                uialert(app.UIFigure, ['Error loading file: ' ME.message], 'Error');
                app.FileNameLabel.Text = {'Loaded File:', 'Error'};
            end
        end

        % Button pushed function: IdentifyOutliersButton
        function IdentifyOutliersButtonPushed(app, event)
            app.OutliersVisible = ~app.OutliersVisible;
            if app.OutliersVisible
                app.updateOutliers();
                app.IdentifyOutliersButton.Text = 'Hide Outliers';
                app.SensitivitySlider.Visible = 'on';
                app.MultiplierLabel.Visible = 'on';
                app.OutlierSTDLabel.Visible = 'on';
                app.AnalysisTargetGroup.Visible = 'on';
            else
                app.hideOutliers();
                app.IdentifyOutliersButton.Text = 'Identify Outliers';
                app.SensitivitySlider.Visible = 'off';
                app.MultiplierLabel.Visible = 'off';
                app.OutlierSTDLabel.Visible = 'off';
                app.AnalysisTargetGroup.Visible = 'off';
            end
        end

        % Value changed function: SensitivitySlider
        function SensitivitySliderValueChanged(app, event)
            % When the slider value changes, just re-run the outlier detection
            app.updateOutliers();
        end

        % Selection changed function: AnalysisTargetGroup
        function AnalysisTargetGroupSelectionChanged(app, event)
            % If outliers are visible, re-run the analysis for the new target
            if app.OutliersVisible
                app.updateOutliers();
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.IdentifyOutliersButton = uibutton(app.LeftPanel, 'push');
            app.IdentifyOutliersButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyOutliersButtonPushed, true);
            app.IdentifyOutliersButton.Text = 'Identify Outliers';
            app.OutlierSTDLabel = uilabel(app.LeftPanel);
            app.OutlierSTDLabel.Text = 'Outlier STD:';
            app.SensitivitySlider = uislider(app.LeftPanel);
            app.SensitivitySlider.Limits = [0.5 4];
            app.SensitivitySlider.ValueChangedFcn = createCallbackFcn(app, @SensitivitySliderValueChanged, true);
            app.SensitivitySlider.Step = 0.5;
            app.SensitivitySlider.Value = 2.5;
            app.MultiplierLabel = uilabel(app.LeftPanel);
            app.MultiplierLabel.Text = '';
            app.FileNameLabel = uilabel(app.LeftPanel);
            app.FileNameLabel.Text = '';
            app.AnalysisTargetGroup = uibuttongroup(app.LeftPanel);
            app.AnalysisTargetGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisTargetGroupSelectionChanged, true);
            app.AnalysisTargetGroup.Title = 'Analysis Target Group';
            app.PressurevsTimeButton = uiradiobutton(app.AnalysisTargetGroup);
            app.PressurevsTimeButton.Text = 'Pressure vs. Time';
            app.PressurevsTimeButton.Value = true;
            app.TemperaturevsTimeButton = uiradiobutton(app.AnalysisTargetGroup);
            app.TemperaturevsTimeButton.Text = 'Temperature vs. Time';
            app.PressurevsTemperatureButton = uiradiobutton(app.AnalysisTargetGroup);
            app.PressurevsTemperatureButton.Text = 'Pressure vs. Temperature';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.BivariateAnalysisTab = uitab(app.TabGroup);
            app.BivariateAnalysisTab.Title = 'Bivariate Analysis';
            app.GridLayout2 = uigridlayout(app.BivariateAnalysisTab);
            app.TimeSeriesAxes = uiaxes(app.GridLayout2);
            title(app.TimeSeriesAxes, 'Title')
            xlabel(app.TimeSeriesAxes, 'X')
            ylabel(app.TimeSeriesAxes, 'Y')
            zlabel(app.TimeSeriesAxes, 'Z')
            app.PressureVsTempAxes = uiaxes(app.GridLayout2);
            title(app.PressureVsTempAxes, 'Title')
            xlabel(app.PressureVsTempAxes, 'X')
            ylabel(app.PressureVsTempAxes, 'Y')
            zlabel(app.PressureVsTempAxes, 'Z')
            app.TimeSeriesCorrelationLabel = uilabel(app.GridLayout2);
            app.TimeSeriesCorrelationLabel.Text = '';
            app.PressureVsTempCorrelationLabel = uilabel(app.GridLayout2);
            app.PressureVsTempCorrelationLabel.Text = '';
            app.UnivariateAnalysisTab = uitab(app.TabGroup);
            app.UnivariateAnalysisTab.Title = 'Univariate Analysis';
            app.GridLayout3 = uigridlayout(app.UnivariateAnalysisTab);
            app.TemperatureHistogramAxes = uiaxes(app.GridLayout3);
            title(app.TemperatureHistogramAxes, 'Title')
            xlabel(app.TemperatureHistogramAxes, 'X')
            ylabel(app.TemperatureHistogramAxes, 'Y')
            zlabel(app.TemperatureHistogramAxes, 'Z')
            app.PressureHistogramAxes = uiaxes(app.GridLayout3);
            title(app.PressureHistogramAxes, 'Title')
            xlabel(app.PressureHistogramAxes, 'X')
            ylabel(app.PressureHistogramAxes, 'Y')
            zlabel(app.PressureHistogramAxes, 'Z')
            app.PressureBoxPlotAxes = uiaxes(app.GridLayout3);
            title(app.PressureBoxPlotAxes, 'Title')
            xlabel(app.PressureBoxPlotAxes, 'X')
            ylabel(app.PressureBoxPlotAxes, 'Y')
            zlabel(app.PressureBoxPlotAxes, 'Z')
            app.TemperatureBoxPlotAxes = uiaxes(app.GridLayout3);
            title(app.TemperatureBoxPlotAxes, 'Title')
            xlabel(app.TemperatureBoxPlotAxes, 'X')
            ylabel(app.TemperatureBoxPlotAxes, 'Y')
            zlabel(app.TemperatureBoxPlotAxes, 'Z')
            app.TemperatureStatisticsLabel = uilabel(app.GridLayout3);
            app.TemperatureStatisticsLabel.Text = '';
            app.PressureStatisticsLabel = uilabel(app.GridLayout3);
            app.PressureStatisticsLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end